classdef ChaseVehicle < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        VehicleChaseUIFigure    matlab.ui.Figure
        InfoPanel               matlab.ui.container.Panel
        ChasingVehicleValue     matlab.ui.control.Label
        ChasingVehicleLabel     matlab.ui.control.Label
        AwarenessRangeValue     matlab.ui.control.Label
        AwarenessRangeLabel     matlab.ui.control.Label
        SettingsPanel           matlab.ui.container.Panel
        ShowVectorsSwitch       matlab.ui.control.Switch
        ShowVectorsSwitchLabel  matlab.ui.control.Label
        InfoRangeSlider         matlab.ui.control.Slider
        InfoRangeSliderLabel    matlab.ui.control.Label
        LockCameraSwitch        matlab.ui.control.Switch
        LockCameraSwitchLabel   matlab.ui.control.Label
        axes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        parent % Description
        scatter_plot
        vehicleId
        raw
    end
    
    methods (Access = public)
        
        function redraw(app)
            cla(app.axes);
            position_table = app.parent.position_table;
            positionX = position_table{:, "X"};
            positionY = position_table{:, "Y"};
            this_x = positionX(app.vehicleId);
            this_y = positionY(app.vehicleId);
            % https://www.mathworks.com/help/stats/pdist.html
            distances = pdist([positionX, positionY]);
            distances_z = squareform(distances);
            vehicles_within_range = find(distances_z(app.vehicleId, :) <= app.raw);
            % to change
            TOLERANCE = app.InfoRangeSlider.Value;
            vehicles_slightly_outside_range = setdiff(find(distances_z(app.vehicleId, :) <= app.raw + TOLERANCE), vehicles_within_range, 'stable');
            positionX_vehicles_within_range = positionX(vehicles_within_range);
            positionY_vehicles_within_range = positionY(vehicles_within_range);
            velocity_vehicles_within_range = position_table{vehicles_within_range, "Speed"} .* position_table{vehicles_within_range, "Heading"};
            vectorU_vehicles_within_range = real(velocity_vehicles_within_range);
            vectorV_vehicles_within_range = imag(velocity_vehicles_within_range);
            positionX_vehicles_slightly_outside_range = positionX(vehicles_slightly_outside_range);
            positionY_vehicles_slightly_outside_range = positionY(vehicles_slightly_outside_range);
            scatter(app.axes, positionX_vehicles_within_range, positionY_vehicles_within_range, [], [0.3010 0.7450 0.9330], "filled");
            hold(app.axes, 'on');
            scatter(app.axes, positionX_vehicles_slightly_outside_range, positionY_vehicles_slightly_outside_range, [], [0.9290 0.6940 0.1250], Marker="x");
            if app.LockCameraSwitch.Value == "On"
                xlim(app.axes, [this_x - app.raw, this_x + app.raw]);
                ylim(app.axes, [this_y - app.raw, this_y + app.raw]);
            end
            all_vehicles_drawn = union(vehicles_within_range, vehicles_slightly_outside_range);
            text(app.axes, positionX(all_vehicles_drawn) + 5, positionY(all_vehicles_drawn) + 5, cellstr(string(all_vehicles_drawn)));
            rectangle(app.axes, Position=[this_x - app.raw, this_y - app.raw, app.raw*2, app.raw*2], Curvature=[1 1], LineStyle="--");
            rectangle(app.axes, Position=[this_x - (app.raw+TOLERANCE), this_y - (app.raw+TOLERANCE), (app.raw+TOLERANCE)*2, (app.raw+TOLERANCE)*2], Curvature=[1 1], LineStyle="--");
            if app.ShowVectorsSwitch.Value == "On"
                quiver(app.axes, positionX_vehicles_within_range, positionY_vehicles_within_range, vectorU_vehicles_within_range, vectorV_vehicles_within_range, 0.25);
            end
            % hold(app.axes, 'off');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, parent, vehicleId, raw)
            app.parent = parent;
            app.vehicleId = vehicleId;
            app.raw = raw;
            app.AwarenessRangeValue.Text = string(raw);
            app.ChasingVehicleValue.Text = string(vehicleId);
            app.InfoRangeSlider.Limits = [0, app.raw*2];
            app.InfoRangeSlider.Value = app.raw;
            app.redraw();
        end

        % Close request function: VehicleChaseUIFigure
        function VehicleChaseUIFigureCloseRequest(app, event)
            try
                app.parent.ChaseVehicleWindowClosed(app.vehicleId);
            catch ME
            end
            delete(app)
            
        end

        % Value changing function: InfoRangeSlider
        function InfoRangeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.redraw();
        end

        % Value changed function: ShowVectorsSwitch
        function ShowVectorsSwitchValueChanged(app, event)
            value = app.ShowVectorsSwitch.Value;
            app.redraw();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.VehicleChaseUIFigure = uifigure('Visible', 'off');
            app.VehicleChaseUIFigure.Name = 'Vehicle Chase';
            app.VehicleChaseUIFigure.CloseRequestFcn = createCallbackFcn(app, @VehicleChaseUIFigureCloseRequest, true);
            app.axes = uiaxes(app.VehicleChaseUIFigure);
            xlabel(app.axes, 'X')
            ylabel(app.axes, 'Y')
            zlabel(app.axes, 'Z')
            app.SettingsPanel = uipanel(app.VehicleChaseUIFigure);
            app.SettingsPanel.Title = 'Settings';
            app.LockCameraSwitchLabel = uilabel(app.SettingsPanel);
            app.LockCameraSwitchLabel.Text = 'Lock Camera';
            app.LockCameraSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.LockCameraSwitch.Value = 'On';
            app.InfoRangeSliderLabel = uilabel(app.SettingsPanel);
            app.InfoRangeSliderLabel.Text = 'Info Range';
            app.InfoRangeSlider = uislider(app.SettingsPanel);
            app.InfoRangeSlider.ValueChangingFcn = createCallbackFcn(app, @InfoRangeSliderValueChanging, true);
            app.ShowVectorsSwitchLabel = uilabel(app.SettingsPanel);
            app.ShowVectorsSwitchLabel.Text = 'Show Vectors';
            app.ShowVectorsSwitch = uiswitch(app.SettingsPanel, 'slider');
            app.ShowVectorsSwitch.ValueChangedFcn = createCallbackFcn(app, @ShowVectorsSwitchValueChanged, true);
            app.InfoPanel = uipanel(app.VehicleChaseUIFigure);
            app.InfoPanel.Title = 'Info';
            app.AwarenessRangeLabel = uilabel(app.InfoPanel);
            app.AwarenessRangeLabel.Text = 'Awareness Range';
            app.AwarenessRangeValue = uilabel(app.InfoPanel);
            app.AwarenessRangeValue.Text = '99';
            app.ChasingVehicleLabel = uilabel(app.InfoPanel);
            app.ChasingVehicleLabel.Text = 'Chasing Vehicle';
            app.ChasingVehicleValue = uilabel(app.InfoPanel);
            app.ChasingVehicleValue.Text = '99';
            % Show the figure after all components are created
            app.VehicleChaseUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ChaseVehicle(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.VehicleChaseUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.VehicleChaseUIFigure)
        end
    end
end