classdef DebugErrors < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        V2XSimErrorDebuggerUIFigure     matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        V2XSimRXErrorDebuggerLabel      matlab.ui.control.Label
        OutputContainerEditField        matlab.ui.control.EditField
        OutputContainerEditFieldLabel   matlab.ui.control.Label
        ExperimentFolderEditField       matlab.ui.control.EditField
        ExperimentFolderEditFieldLabel  matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        ExperimentIDEditField           matlab.ui.control.EditField
        ExperimentIDEditFieldLabel      matlab.ui.control.Label
        RightPanel                      matlab.ui.container.Panel
        DataPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        GridLayout3                     matlab.ui.container.GridLayout
        HighlightVehiclesEditField      matlab.ui.control.EditField
        HighlightVehiclesEditFieldLabel  matlab.ui.control.Label
        ShowSelectedVehicles            matlab.ui.control.Button
        ShowAllVehicles                 matlab.ui.control.Button
        HideAllVehicles                 matlab.ui.control.Button
        BlockedTextArea                 matlab.ui.control.TextArea
        BlockedTextAreaLabel            matlab.ui.control.Label
        ActiveTX                        matlab.ui.control.TextArea
        TransmittingLabel               matlab.ui.control.Label
        ErrorsTable                     matlab.ui.control.Table
        BRAllocationGraph               matlab.ui.control.UIAxes
        TimePanel                       matlab.ui.container.Panel
        Time                            matlab.ui.control.Label
        MinorTimeSpinner                matlab.ui.control.Spinner
        IntraperiodLabel                matlab.ui.control.Label
        MinorTimeSlider                 matlab.ui.control.Slider
        MajorTimeSpinner                matlab.ui.control.Spinner
        MajorTimeSpinnerLabel           matlab.ui.control.Label
        JumptoNextRXErrorButton         matlab.ui.control.Button
        MajorTimeSlider                 matlab.ui.control.Slider
        ErrorsTableContextMenu          matlab.ui.container.ContextMenu
        ChaseTX                         matlab.ui.container.Menu
        ChaseRXSuccesses                matlab.ui.container.Menu
        ChaseRXFailures                 matlab.ui.container.Menu
        ActiveTXContextMenu             matlab.ui.container.ContextMenu
        Menu                            matlab.ui.container.Menu
        Menu2                           matlab.ui.container.Menu
        BRGraphContextMenu              matlab.ui.container.ContextMenu
        FindVehicleBR                   matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        errors_timetable timetable % Errors Timetable
        br_topology struct
        br_id_timetable timetable
        BRGraphStruct struct
        position_timetable timetable
        activeTX_timetable timetable
        br_to_vehicle_mapping
        minorTimeXLine
        chase_windows = dictionary()
    end
    
    properties (Access = public)
        position_table table % Description
    end
    
    methods (Access = private)
        function drawBRGraph(app)
            app.BRGraphStruct = BRGraphUpdate("BRGraphStruct", app.BRGraphStruct, "br_to_vehicle_mapping", app.br_to_vehicle_mapping);
        end

        function updateBRGraph(app, time)
            % todo
            app.br_to_vehicle_mapping = table2array(timetable2table(tail(app.br_id_timetable(timerange(seconds(0), seconds(time),'closed'), :), 1), ConvertRowTimes=false));
            app.drawBRGraph();
        end
        
        function results = JumpToTime(app, time, event)
            app.DataPanel.Enable = "off";
            ALLOCATION_PERIOD = 0.1;
            % synchronise time control UIs
            MAJOR_TIME = floor((time+0.0001) / ALLOCATION_PERIOD) * ALLOCATION_PERIOD;
            MINOR_TIME = mod(time, ALLOCATION_PERIOD);
            app.MajorTimeSlider.Value = MAJOR_TIME;
            app.MajorTimeSpinner.Value = MAJOR_TIME;
            app.MinorTimeSlider.Value = MINOR_TIME;
            app.MinorTimeSpinner.Value = MINOR_TIME;
            
            this_allocation_period = timerange(seconds(floor(time / ALLOCATION_PERIOD) * ALLOCATION_PERIOD), seconds(time), 'closed');
            app.updateBRGraph(time);
            error_timetable_view = timetable2table(app.errors_timetable(this_allocation_period, :));
            error_timetable_view.Time = string(error_timetable_view.Time);
            app.ErrorsTable.Data = error_timetable_view{:, :};
            app.minorTimeXLine.Value = MINOR_TIME;
            position_table_binary_data = tail(app.position_timetable(timerange(-inf,seconds(time),'closed'), :), 1).Position;
            app.position_table = getArrayFromByteStream(uint8(str2num(position_table_binary_data)));
            if isConfigured(app.chase_windows)
                chase_window_keys = app.chase_windows.keys;
                for i=1:length(chase_window_keys)
                    chase_window_key = chase_window_keys(i);
                    app.chase_windows(chase_window_key).redraw();
                end
            end

            active_tx = app.activeTX_timetable(this_allocation_period, :);
            delete(app.ActiveTXContextMenu.Children);
            if ~isempty(active_tx)
                for i=1:size(active_tx,1)
                    if ismissing(active_tx{i, 1})
                        continue
                    end
                    tx = str2num(active_tx{i, 1});
                    for j=1:length(tx)
                        uimenu(app.ActiveTXContextMenu, 'Text', sprintf("Chase %d", tx(j)), 'UserData', tx(j), 'MenuSelectedFcn', @app.ChaseMenuItemCallback);
                    end
                end
                app.ActiveTX.Value = string(active_tx{:, 1});
            else
                app.ActiveTX.Value = "";
            end
            app.DataPanel.Enable = "on";
        end
        
        function ChaseMenuItemCallback(app, src, event)
            tx = src.UserData;
            app.chase_windows(tx) = ChaseVehicle(app, tx, 91);
        end
    end
    
    methods (Access = public)
        
        function ChaseVehicleWindowClosed(app, vehicleId)
            app.chase_windows(vehicleId) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RightPanel.Enable = "off";
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.RightPanel.Enable = "off";
            output_container = app.OutputContainerEditField.Value;
            experiment_folder = app.ExperimentFolderEditField.Value;
            experiment_id = app.ExperimentIDEditField.Value;
            [app.errors_timetable, app.br_topology, app.br_id_timetable, app.position_timetable, app.activeTX_timetable] = LoadExperiment(output_container, experiment_folder, experiment_id);
            br_graph_init_args = namedargs2cell(app.br_topology);
            app.BRGraphStruct = BRGraphInit(br_graph_init_args{:}, axes=app.BRAllocationGraph);
            app.MajorTimeSpinner.Limits = [1 round(max(app.position_timetable.Properties.RowTimes)/seconds(1))];
            app.MajorTimeSlider.Limits = [1 round(max(app.position_timetable.Properties.RowTimes)/seconds(1))];
            app.MajorTimeSlider.MinorTicks = 1:0.1:round(max(app.position_timetable.Properties.RowTimes)/seconds(1));
            app.MajorTimeSlider.MajorTicks = 1:round(max(app.position_timetable.Properties.RowTimes)/seconds(1));
            app.chase_windows = dictionary();
            app.minorTimeXLine = xline(app.BRAllocationGraph, 0, '--r');
            app.RightPanel.Enable = "on";
        end

        % Value changing function: MajorTimeSlider
        function MajorTimeSliderValueChanging(app, event)
            selected_major_time = event.Value;
            JumpToTime(app, selected_major_time + app.MinorTimeSpinner.Value, event);
        end

        % Value changed function: MajorTimeSpinner
        function MajorTimeSpinnerValueChanged(app, event)
            selected_major_time = event.Value;
            JumpToTime(app, selected_major_time + app.MinorTimeSpinner.Value, event);
        end

        % Button pushed function: JumptoNextRXErrorButton
        function JumptoNextRXErrorButtonPushed(app, event)
            current_epoch = floor(app.MajorTimeSlider.Value);
            ALLOCATION_INTERVAL = 0.1; % to change
            current_time = current_epoch * ALLOCATION_INTERVAL;
            error_time_window_begin = seconds(current_time);
            error_time_window_end = app.errors_timetable.Time(find(app.errors_timetable.Time > error_time_window_begin, 1 , 'first'));
            new_epoch = floor(seconds(error_time_window_end) / ALLOCATION_INTERVAL);
            disp(new_epoch);
            JumpToTime(app, new_epoch, event);
        end

        % Menu selected function: ChaseTX
        function ChaseTXSelected(app, event)
            i = app.ErrorsTable.Selection;
            tx = str2double(app.ErrorsTable.Data(i, 2));
            app.chase_windows(tx) = ChaseVehicle(app, tx, 300);
        end

        % Close request function: V2XSimErrorDebuggerUIFigure
        function V2XSimErrorDebuggerUIFigureCloseRequest(app, event)
            try
                if isConfigured(app.chase_windows)
                    chase_window_keys = app.chase_windows.keys;
                    for i=1:length(chase_window_keys)
                        chase_window_key = chase_window_keys(i);
                        delete(app.chase_windows(chase_window_key));
                    end
                end
            catch me
            end
            delete(app)
            
        end

        % Value changing function: MinorTimeSlider
        function MinorTimeSliderValueChanging(app, event)
            selected_minor_time = event.Value;
            JumpToTime(app, app.MajorTimeSpinner.Value + selected_minor_time, event);
            
        end

        % Value changed function: MinorTimeSpinner
        function MinorTimeSpinnerValueChanged(app, event)
            selected_minor_time = event.Value;
            JumpToTime(app, app.MajorTimeSpinner.Value + selected_minor_time, event);
        end

        % Context menu opening function: ActiveTXContextMenu
        function ActiveTXContextMenuOpening(app, event)
            
        end

        % Menu selected function: FindVehicleBR
        function FindVehicleBRMenuSelected(app, event)
            answer = inputdlg({'Enter vehicle IDs separated by space'});
            vehicle_ids = str2num(answer{1});
            for v_i = 1:numel(vehicle_ids)
                vehicle_id = vehicle_ids(v_i);
                for br_j = 1:numel(app.br_to_vehicle_mapping)
                    vehicles_using_this_br = str2num(app.br_to_vehicle_mapping(br_j));
                    if ismember(vehicle_id, vehicles_using_this_br)
                        fprintf("%d is using BR %d\n", vehicle_id, br_j);
                        break
                    end
                end
            end
        end

        % Button pushed function: HideAllVehicles
        function HideAllVehiclesButtonPushed(app, event)
            all_vehicle_ids = unique(app.position_table{:, "Vehicle"});
            app.BRGraphStruct.hiddenVehicles = all_vehicle_ids;
            app.drawBRGraph();
        end

        % Button pushed function: ShowAllVehicles
        function ShowAllVehiclesButtonPushed(app, event)
            app.BRGraphStruct.hiddenVehicles = [];
            app.drawBRGraph();
        end

        % Button pushed function: ShowSelectedVehicles
        function ShowSelectedVehiclesButtonPushed(app, event)
            all_vehicle_ids = unique(app.position_table{:, "Vehicle"});
            app.BRGraphStruct.hiddenVehicles = setdiff(all_vehicle_ids, str2num(app.HighlightVehiclesEditField.Value));
            app.drawBRGraph();
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.V2XSimErrorDebuggerUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {818, 818};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {248, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.V2XSimErrorDebuggerUIFigure = uifigure('Visible', 'off');
            app.V2XSimErrorDebuggerUIFigure.Name = 'V2XSim Error Debugger';
            app.V2XSimErrorDebuggerUIFigure.CloseRequestFcn = createCallbackFcn(app, @V2XSimErrorDebuggerUIFigureCloseRequest, true);
            app.V2XSimErrorDebuggerUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.V2XSimErrorDebuggerUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ExperimentIDEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentIDEditFieldLabel.Text = 'Experiment ID';
            app.ExperimentIDEditField = uieditfield(app.LeftPanel, 'text');
            app.ExperimentIDEditField.Value = '1';
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ExperimentFolderEditFieldLabel = uilabel(app.LeftPanel);
            app.ExperimentFolderEditFieldLabel.Text = 'Experiment Folder';
            app.ExperimentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.ExperimentFolderEditField.Value = 'test';
            app.OutputContainerEditFieldLabel = uilabel(app.LeftPanel);
            app.OutputContainerEditFieldLabel.Text = 'Output Container';
            app.OutputContainerEditField = uieditfield(app.LeftPanel, 'text');
            app.OutputContainerEditField.Value = 'Output';
            app.V2XSimRXErrorDebuggerLabel = uilabel(app.LeftPanel);
            app.V2XSimRXErrorDebuggerLabel.Text = {'V2XSim'; 'RXError Debugger'};
            app.RightPanel = uipanel(app.GridLayout);
            app.TimePanel = uipanel(app.RightPanel);
            app.TimePanel.Title = 'Time Selection';
            app.MajorTimeSlider = uislider(app.TimePanel);
            app.MajorTimeSlider.ValueChangingFcn = createCallbackFcn(app, @MajorTimeSliderValueChanging, true);
            app.JumptoNextRXErrorButton = uibutton(app.TimePanel, 'push');
            app.JumptoNextRXErrorButton.ButtonPushedFcn = createCallbackFcn(app, @JumptoNextRXErrorButtonPushed, true);
            app.JumptoNextRXErrorButton.Text = 'Jump to Next RX Error';
            app.MajorTimeSpinnerLabel = uilabel(app.TimePanel);
            app.MajorTimeSpinnerLabel.Text = 'Major Time';
            app.MajorTimeSpinner = uispinner(app.TimePanel);
            app.MajorTimeSpinner.Step = 0.1;
            app.MajorTimeSpinner.Limits = [1 Inf];
            app.MajorTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MajorTimeSpinnerValueChanged, true);
            app.MajorTimeSpinner.Value = 1;
            app.MinorTimeSlider = uislider(app.TimePanel);
            app.MinorTimeSlider.Limits = [0 0.1];
            app.MinorTimeSlider.ValueChangingFcn = createCallbackFcn(app, @MinorTimeSliderValueChanging, true);
            app.MinorTimeSlider.MinorTicks = [];
            app.IntraperiodLabel = uilabel(app.TimePanel);
            app.IntraperiodLabel.Text = 'Minor Time';
            app.MinorTimeSpinner = uispinner(app.TimePanel);
            app.MinorTimeSpinner.Step = 0.001;
            app.MinorTimeSpinner.Limits = [0 0.1];
            app.MinorTimeSpinner.ValueDisplayFormat = '%.4f';
            app.MinorTimeSpinner.ValueChangedFcn = createCallbackFcn(app, @MinorTimeSpinnerValueChanged, true);
            app.MinorTimeSpinner.Value = 0.1;
            app.Time = uilabel(app.TimePanel);
            app.DataPanel = uipanel(app.RightPanel);
            app.DataPanel.Title = 'Panel';
            app.GridLayout2 = uigridlayout(app.DataPanel);
            app.BRAllocationGraph = uiaxes(app.GridLayout2);
            title(app.BRAllocationGraph, 'BR Allocations')
            xlabel(app.BRAllocationGraph, 'X')
            ylabel(app.BRAllocationGraph, 'Y')
            zlabel(app.BRAllocationGraph, 'Z')
            app.ErrorsTable = uitable(app.GridLayout2);
            app.ErrorsTable.ColumnName = {'Time'; 'tx'; 'rx_successes'; 'rx_errors'};
            app.ErrorsTable.RowName = {};
            app.ErrorsTable.ColumnSortable = [true false false false];
            app.ErrorsTable.SelectionType = 'row';
            app.ErrorsTable.Multiselect = 'off';
            app.TransmittingLabel = uilabel(app.GridLayout2);
            app.TransmittingLabel.Text = 'Transmitting';
            app.ActiveTX = uitextarea(app.GridLayout2);
            app.ActiveTX.Editable = 'off';
            app.GridLayout3 = uigridlayout(app.GridLayout2);
            app.BlockedTextAreaLabel = uilabel(app.GridLayout3);
            app.BlockedTextAreaLabel.Text = 'Blocked';
            app.BlockedTextArea = uitextarea(app.GridLayout3);
            app.HideAllVehicles = uibutton(app.GridLayout3, 'push');
            app.HideAllVehicles.ButtonPushedFcn = createCallbackFcn(app, @HideAllVehiclesButtonPushed, true);
            app.HideAllVehicles.Text = 'Hide All Vehicles';
            app.ShowAllVehicles = uibutton(app.GridLayout3, 'push');
            app.ShowAllVehicles.ButtonPushedFcn = createCallbackFcn(app, @ShowAllVehiclesButtonPushed, true);
            app.ShowAllVehicles.Text = 'Show All Vehicles';
            app.ShowSelectedVehicles = uibutton(app.GridLayout3, 'push');
            app.ShowSelectedVehicles.ButtonPushedFcn = createCallbackFcn(app, @ShowSelectedVehiclesButtonPushed, true);
            app.ShowSelectedVehicles.Text = 'Show Highlighted Vehicles >';
            app.HighlightVehiclesEditFieldLabel = uilabel(app.GridLayout3);
            app.HighlightVehiclesEditFieldLabel.Text = 'Highlight Vehicles:';
            app.HighlightVehiclesEditField = uieditfield(app.GridLayout3, 'text');
            app.ErrorsTableContextMenu = uicontextmenu(app.V2XSimErrorDebuggerUIFigure);
            app.ChaseTX = uimenu(app.ErrorsTableContextMenu);
            app.ChaseTX.MenuSelectedFcn = createCallbackFcn(app, @ChaseTXSelected, true);
            app.ChaseTX.Text = 'Chase TX';
            app.ChaseRXSuccesses = uimenu(app.ErrorsTableContextMenu);
            app.ChaseRXSuccesses.Text = 'Chase RX Successes...';
            app.ChaseRXFailures = uimenu(app.ErrorsTableContextMenu);
            app.ChaseRXFailures.Text = 'Chase RX Failures...';
            % Assign app.ErrorsTableContextMenu
            app.ErrorsTable.ContextMenu = app.ErrorsTableContextMenu;
            app.ActiveTXContextMenu = uicontextmenu(app.V2XSimErrorDebuggerUIFigure);
            app.ActiveTXContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ActiveTXContextMenuOpening, true);
            app.Menu = uimenu(app.ActiveTXContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ActiveTXContextMenu);
            app.Menu2.Text = 'Menu2';
            % Assign app.ActiveTXContextMenu
            app.ActiveTX.ContextMenu = app.ActiveTXContextMenu;
            app.BRGraphContextMenu = uicontextmenu(app.V2XSimErrorDebuggerUIFigure);
            app.FindVehicleBR = uimenu(app.BRGraphContextMenu);
            app.FindVehicleBR.MenuSelectedFcn = createCallbackFcn(app, @FindVehicleBRMenuSelected, true);
            app.FindVehicleBR.Text = 'Find vehicle''s BR';
            % Assign app.BRGraphContextMenu
            app.BRAllocationGraph.ContextMenu = app.BRGraphContextMenu;
            % Show the figure after all components are created
            app.V2XSimErrorDebuggerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DebugErrors

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.V2XSimErrorDebuggerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.V2XSimErrorDebuggerUIFigure)
        end
    end
end